classdef login < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        EnterYourPasswordLabel      matlab.ui.control.Label
        SubmitButton                matlab.ui.control.Button
        KhattabRadioProjectLabel    matlab.ui.control.Label
        LoginButton                 matlab.ui.control.Button
        CreateButton                matlab.ui.control.Button
        EnteryourpasswordEditField  matlab.ui.control.EditField
    end


    properties (Access = private)
        MainApp % Main app that has the logic
        password % Password to be shared with Main App
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Button pushed function: CreateButton
        function CreateButtonPushed(app, event)
            app.CreateButton.Enable = "off";
            app.EnteryourpasswordEditField.Visible = "on";
            app.EnterYourPasswordLabel.Visible = "on";
            app.SubmitButton.Visible = "on";
            app.LoginButton.Visible = "on";
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Delete both apps

            delete(app)

        end

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
            app.password = app.EnteryourpasswordEditField.Value;
            msgbox("Password created successfully!");
            app.EnteryourpasswordEditField.Enable = "off";
            app.EnterYourPasswordLabel.Enable = "off";
            app.SubmitButton.Enable = "off";
            app.LoginButton.Enable = "on";
            %delete(app)
        end

        % Button pushed function: LoginButton
        function LoginButtonPushed(app, event)
            app.MainApp = mainapp(app, app.password);
            app.CreateButton.Enable = "on";
            app.EnterYourPasswordLabel.Enable = "on";
            app.SubmitButton.Enable = "on";
            app.EnteryourpasswordEditField.Enable = "on";
            app.EnterYourPasswordLabel.Visible = "off";
            app.SubmitButton.Visible = "off";
            app.EnteryourpasswordEditField.Visible = "off";
            app.LoginButton.Enable = "off";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.EnteryourpasswordEditField = uieditfield(app.UIFigure, 'text');
            app.EnteryourpasswordEditField.Visible = 'off';
            app.CreateButton = uibutton(app.UIFigure, 'push');
            app.CreateButton.ButtonPushedFcn = createCallbackFcn(app, @CreateButtonPushed, true);
            app.CreateButton.Text = {'Create'; ''};
            app.LoginButton = uibutton(app.UIFigure, 'push');
            app.LoginButton.ButtonPushedFcn = createCallbackFcn(app, @LoginButtonPushed, true);
            app.LoginButton.Enable = 'off';
            app.LoginButton.Text = 'Login';
            app.KhattabRadioProjectLabel = uilabel(app.UIFigure);
            app.KhattabRadioProjectLabel.Text = 'Khattab Radio Project';
            app.SubmitButton = uibutton(app.UIFigure, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Visible = 'off';
            app.SubmitButton.Text = 'Submit';
            app.EnterYourPasswordLabel = uilabel(app.UIFigure);
            app.EnterYourPasswordLabel.Visible = 'off';
            app.EnterYourPasswordLabel.Text = 'Enter Your Password';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = login

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end