classdef mainapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ProjectMenu                 matlab.ui.container.Menu
        ResetMenu                   matlab.ui.container.Menu
        HelpMenu                    matlab.ui.container.Menu
        TipsandTricksMenu           matlab.ui.container.Menu
        Toolbar                     matlab.ui.container.Toolbar
        PushTool                    matlab.ui.container.toolbar.PushTool
        PushTool2                   matlab.ui.container.toolbar.PushTool
        TabGroup                    matlab.ui.container.TabGroup
        Output1and2and3Tab          matlab.ui.container.Tab
        MeasurementsPanel           matlab.ui.container.Panel
        UITable                     matlab.ui.control.Table
        OutputsPanel                matlab.ui.container.Panel
        dmEditField                 matlab.ui.control.NumericEditField
        dmEditFieldLabel            matlab.ui.control.Label
        EnterButton                 matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
        ModelParametersPanel        matlab.ui.container.Panel
        CalculateButton             matlab.ui.control.Button
        Label                       matlab.ui.control.Label
        PowerUnitsDropDown          matlab.ui.control.DropDown
        PowerUnitsDropDownLabel     matlab.ui.control.Label
        NumberofSamplesKnob         matlab.ui.control.Knob
        NumberofSamplesLabel        matlab.ui.control.Label
        PLdoEditField               matlab.ui.control.NumericEditField
        PLdoEditFieldLabel          matlab.ui.control.Label
        EditField                   matlab.ui.control.NumericEditField
        EditFieldLabel              matlab.ui.control.Label
        nEditField                  matlab.ui.control.NumericEditField
        nEditFieldLabel             matlab.ui.control.Label
        domEditField                matlab.ui.control.NumericEditField
        domEditFieldLabel           matlab.ui.control.Label
        Output4Tab                  matlab.ui.container.Tab
        ReportButton                matlab.ui.control.Button
        ModelParametersPanel_2      matlab.ui.container.Panel
        Label_2                     matlab.ui.control.Label
        NumberofSamplesKnob_2       matlab.ui.control.Knob
        NumberofSamplesKnob_2Label  matlab.ui.control.Label
        EditField_2                 matlab.ui.control.NumericEditField
        EditField_2Label            matlab.ui.control.Label
        nEditField_2                matlab.ui.control.NumericEditField
        nEditField_2Label           matlab.ui.control.Label
        dEditField                  matlab.ui.control.NumericEditField
        dEditFieldLabel             matlab.ui.control.Label
        domEditField_2              matlab.ui.control.NumericEditField
        domEditField_2Label         matlab.ui.control.Label
        ExtraParametersPanel        matlab.ui.container.Panel
        CalculateButtonGroup        matlab.ui.container.ButtonGroup
        SeperationButton            matlab.ui.control.RadioButton
        TimeButton                  matlab.ui.control.RadioButton
        TimeEditField               matlab.ui.control.NumericEditField
        TimeEditFieldLabel          matlab.ui.control.Label
        RequiredPREditField         matlab.ui.control.NumericEditField
        RequiredPREditFieldLabel    matlab.ui.control.Label
        AntennaParametersPanel      matlab.ui.container.Panel
        FMHzEditField               matlab.ui.control.NumericEditField
        FMHzEditFieldLabel          matlab.ui.control.Label
        GrEditField                 matlab.ui.control.NumericEditField
        GrEditFieldLabel            matlab.ui.control.Label
        GtEditField                 matlab.ui.control.NumericEditField
        GtEditFieldLabel            matlab.ui.control.Label
        PtEditField                 matlab.ui.control.NumericEditField
        PtEditFieldLabel            matlab.ui.control.Label
        CommandWindowLabel          matlab.ui.control.Label
        CommandWindowTextArea       matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        measurements_array % Description
        d_array
        number_of_samples
        CallingApp % login App
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, password)
            app.CallingApp  = caller;
            
            % Process the password
            pw = passwordUI('Query', 'AlphaNum');
            % Counter for wrong pw entries
            WrongCount = 0;
            
            while strcmp(pw,password) == 0
                WrongCount = WrongCount + 1;
                if WrongCount == 3
                    delete(app)
                    break
                end
                msgbox("Wrong Password!","Error","custom",imread("bedo.png"));
                pw = passwordUI('Query', 'AlphaNum');
            end
            if strcmp(pw,password) == 1
                
                f = msgbox("Welcome Back");
            end
            
            
            %app.d_array = {" "};
        end

        % Clicked callback: PushTool
        function PushToolClicked(app, event)
              %Read Json File
              file_json = uigetfile({'*.json'},'Select Input Parameters');
              file = jsondecode(fileread(file_json));  
              fields = fieldnames(file.ModelParameters.Measurements);
              
              
              temp = fields;
              
              corrupted_file = 0;
              app.domEditField.Value = file.ModelParameters.do;  
              
              %Parse Values
              for counter = 1 : 1 : length(fields)
                 
                   fields{counter}(1) = " ";
                   x = str2num(fields{counter}(1,:));

                   if x  < app.domEditField.Value
                       
                       %Reset All Line Edits Values and Color Font
                       cla(app.UIAxes) 
                       app.PLdoEditField.Value  = 0;
                       app.UITable.Data = [];
                       app.nEditField.Value     = 0;
                       app.EditField.Value      = 0;          
                       app.SamplesEditField.Value  = 0;                          
                       app.CommandWindowTextArea.FontColor = [0.00,0.00,0.00];
                       app.CommandWindowTextArea.Value = sprintf('[Obelos]: d = %d is smaller than do = %d \n[Obelos]: File is corrupted. Process is terminated ' ,x , app.domEditField.Value); 
                       corrupted_file =1 ;                      
                       return;
                   end    

                    fields_array(counter) = x;
                   app.measurements_array = [ app.measurements_array  getfield(file.ModelParameters.Measurements,temp{counter}) ];
                  
              end      
              powerunits = app.PowerUnitsDropDown.Value;      

              %Output on command window
              if corrupted_file == 0  
                  t= table(fields_array.',app.measurements_array.');
                  app.UITable.Data = t;
                  
                  if strcmp(powerunits,"dB")
                  app.UIAxes.YLabel.String ="Power Received (dB)";
                  elseif  strcmp(powerunits,"decimal")
                  app.UIAxes.YLabel.String ="Power Received (decimal)";                      
                  end
                  
                  scatter(app.UIAxes,fields_array,app.measurements_array);
                  app.CommandWindowTextArea.Value = sprintf('[Obelos]: File is Imported Successfully!\n[Obelos]: Figure is Plotted');  
                  app.CommandWindowTextArea.FontColor = [0.47,0.67,0.19];  %Green
              end


        end

        % Clicked callback: PushTool2
        function PushTool2Clicked(app, event)

                file2.ModelParameters.d  = app.dmEditField.Value;
                file2.ModelParameters.do = app.domEditField.Value;
                file2.ModelParameters.n  = app.nEditField.Value;
                file2.ModelParameters.x_ = app.EditField.Value;          
                file2.ModelParameters.k  = app.SamplesEditField.Value;
                file2.ModelParameters.Measurnments = {};
                
                 
                file_json = jsonencode(file2,'PrettyPrint',true);
                
                fileID = fopen('Output.json','w');
                fprintf(fileID,'%s',file_json);
                fclose(fileID);


              %Output on command window
              app.CommandWindowTextArea.Value = sprintf('[Obelos]: File is Exported Successfully!');  
              app.CommandWindowTextArea.FontColor = [0.47,0.67,0.19];
        end

        % Value changed function: PowerUnitsDropDown
        function PowerUnitsDropDownValueChanged(app, event)
            value = app.PowerUnitsDropDown.Value;
            
            app.CommandWindowTextArea.Value = sprintf('[Obelos]: Power Units are %s',value); 
        end

        % Button pushed function: CalculateButton
        function SubmitButtonPushed(app, event)
            powerunits = app.PowerUnitsDropDown.Value;              
            temp = app.CommandWindowTextArea.Value;
            
            syms n_estimated;
            
            n_estimated = 0;
            
            
            if strcmp(powerunits,"dB")
                
             PL_do_decimal = app.PLdoEditField.Value;
                
             for d_counter = 1 : 1 : length(app.d_array)
                    
                    Mean = PL_do_decimal + 10 * app.nEditField.Value * log10( (app.d_array(d_counter))/(app.domEditField.Value));
                    
                    PL_d_decimal(d_counter,:) = Mean + app.EditField.Value .* randn(app.NumberofSamplesKnob.Value,1) ;
                    
                 %   PL_Model(d_counter) = PL_do_decimal + 10 * n_estimated * log10(d(d_counter)/do);    
                               
                 %   sum2 = sum2 + ( PL_d_decimal(d_counter,:) - PL_Model(d_counter) ) .^ 2;    
                    
                 %   exp = sum(sum2); 
                    
                 %   tt =  diff(exp/length(app.d_array) * app.NumberofSamplesKnob);
                    
                 %   n_estimated2 = double(solve(tt==0,n_estimated));
                    
                 %   disp(n_estimated2);
                    
             end
                
             scatter(app.UIAxes,app.d_array,PL_d_decimal);
             
             app.UIAxes.YLabel.String ="Path Loss (dB)";   
             app.ReceivedPowerEditField.Value = 0;
             app.CommandWindowTextArea.Value = sprintf('[Obelos]: Received Power is Calculated %d dB',app.ReceivedPowerEditField.Value);   
                
            else
                
           % Pr_do_decimal = app.PtEditField.Value *  app.GtEditField.Value * app.GrEditField.Value * ( (300000000 / (app.FMHzEditField.Value*10^6) ) / (4 * pi * app.domEditField.Value ) ) ^ 2;
           % P_do_decimal = app.PLdoEditField.Value;    
            
           % Pr_d_decimal =  Pr_do_decimal * (app.domEditField.Value / app.dmEditField.Value ) ^  app.nEditField.Value ;
           
            PL_do_decimal = app.PLdoEditField.Value;
            
            for d_counter = 1 : 1 : length(app.d_array)
                
                Mean = PL_do_decimal + 10 * app.nEditField.Value * log10( (app.d_array(d_counter))/(app.domEditField.Value));   %Change Equations
                PL_d_decimal(d_counter,:) = Mean + app.EditField.Value .* randn(app.NumberofSamplesKnob.Value,1) ;              %Change Equations
                
            end
            
            
             scatter(app.UIAxes,app.d_array,PL_d_decimal);
            
             app.CommandWindowTextArea.Value = sprintf('[Obelos]: Figure is Plotted '); 
            
            
             app.UIAxes.YLabel.String ="Path Loss (decimal)";    
             app.UIAxes.Title.String = sprintf("Scatter Plot for n = %d" , app.nEditField.Value);                
                
          %   app.ReceivedPowerEditField.Value = Pr_d_decimal ; 
             
          %   app.CommandWindowTextArea.Value = sprintf("\n[Obelos]: Received Power is Calculated %d decimal",app.ReceivedPowerEditField.Value);
             
          %   app.CommandWindowTextArea.Value = strcat(temp,app.CommandWindowTextArea.Value);
             
            end
            
            
        end

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
            if app.dmEditField.Value > app.domEditField.Value
                
                if isempty(find(app.d_array == app.dmEditField.Value, 1 ))
                
                app.UITable.Data = [];
                          
                app.d_array = [app.d_array  app.dmEditField.Value] ;
                
                t= table(app.d_array.');
                app.UITable.Data = t; 
                app.CommandWindowTextArea.FontColor = [0.47,0.67,0.19];
                app.CommandWindowTextArea.Value = sprintf("[Obelos]: Distance d = %d is added to Measurements.",app.dmEditField.Value);
                
                else
                    
                app.CommandWindowTextArea.FontColor = [0.00,0.00,0.00];
                app.CommandWindowTextArea.Value = sprintf("[Obelos]: Distance d = %d is already in Measurements.",app.dmEditField.Value);                    
                    
                end
                
            else
                app.CommandWindowTextArea.FontColor = [0.00,0.00,0.00];
                app.CommandWindowTextArea.Value = sprintf("[Obelos]: Distance d = %d is less than do = %d ",app.dmEditField.Value,app.domEditField.Value);
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % reEnable the button in login app
            %app.CallingApp.CreateButton.Enable = "on";
            
            delete(app)
            
        end

        % Value changing function: NumberofSamplesKnob
        function NumberofSamplesKnobValueChanging(app, event)
            changingValue = event.Value;
            
            app.number_of_samples = floor(event.Value);
            
            app.Label.Text = num2str((floor(changingValue)));
            
            PL_do_decimal = app.PLdoEditField.Value;
            
            if strcmp(powerunits,"dB")
                
                for d_counter = 1 : 1 : length(app.d_array)
                        
                        Mean = PL_do_decimal + 10 * app.nEditField.Value * log10( (app.d_array(d_counter))/(app.domEditField.Value));
                        PL_d_decimal(d_counter,:) = Mean + app.EditField.Value .* randn( app.number_of_samples,1) ;
                        
                end
                    
                scatter(app.UIAxes,app.d_array,PL_d_decimal);
            
            else
                
                
            end
            
        end

        % Menu selected function: TipsandTricksMenu
        function TipsandTricksMenuSelected(app, event)
                app.CommandWindowTextArea.FontColor = [0.00,0.00,0.00];
                app.CommandWindowTextArea.Value = sprintf("[Obelos]: Welcome From Tips and Tricks");
        end

        % Menu selected function: ResetMenu
        function ResetMenuSelected(app, event)
                 %Reset All Line Edits Values and Color Font
                cla(app.UIAxes) 
                app.PLdoEditField.Value  = 0;
                app.d_array = [];
                app.UITable.Data = [];
                app.nEditField.Value     = 0;
                app.EditField.Value      = 0; 
                app.domEditField.Value = 0;
                app.CommandWindowTextArea.FontColor = [0.00,0.00,0.00];                
                app.CommandWindowTextArea.Value = sprintf("[Obelos]: Project is resetted.");            
        end

        % Button pushed function: ReportButton
        function ReportButtonPushed(app, event)
            Pr_do = app.PtEditField.Value *  app.GtEditField.Value * app.GrEditField.Value * ( (300000000 / (app.FMHzEditField.Value*10^6) ) / (4 * pi * app.domEditField_2.Value ) ) ^ 2;
            Pr_do_dB = 10*log10(Pr_do);
            
            if strcmp(app.CalculateButtonGroup.SelectedObject.Text,"Time") 
            
            Pr_d_dB = Pr_do_dB - 10 * app.nEditField_2.Value * log10(app.dEditField.Value/app.domEditField_2.Value);
            
            app.TimeEditField.Value = qfunc((Pr_d_dB-app.RequiredPREditField.Value)/app.EditField.Value); 
            
            elseif strcmp(app.CalculateButtonGroup.SelectedObject.Text,"Seperation")
             
                qfunction_input = qfuncinv(app.TimeEditField.Value) ;
                
                Prd_Omega = qfunction_input * app.EditField_2.Value;
                
                Pr_d_dB = Prd_Omega + app.RequiredPREditField.Value; 
                
                log_ = ( -1 * (Pr_d_dB - Pr_do_dB) ) / (10 * app.nEditField_2.Value);
                
                dmax = ( 10 ^ log_ ) * app.domEditField_2.Value;
                
                
                app.CommandWindowTextArea.Value = sprintf("[Obelos]: Max distance d = %d ",dmax);


            end
        end

        % Selection changed function: CalculateButtonGroup
        function CalculateButtonGroupSelectionChanged(app, event)
            selectedButton = app.CalculateButtonGroup.SelectedObject;
            
            if strcmp(selectedButton.Text,"Time") 
                
                app.TimeEditField.Editable = 0;

                
                display("HELLO FROM Time");
                
            elseif strcmp(selectedButton.Text,"Seperation")
                
                display("HELLO FROM SEPERATION");
                

                app.TimeEditField.Editable = 1;
                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ProjectMenu = uimenu(app.UIFigure);
            app.ProjectMenu.Text = 'Project';
            app.ResetMenu = uimenu(app.ProjectMenu);
            app.ResetMenu.MenuSelectedFcn = createCallbackFcn(app, @ResetMenuSelected, true);
            app.ResetMenu.Text = 'Reset';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.TipsandTricksMenu = uimenu(app.HelpMenu);
            app.TipsandTricksMenu.MenuSelectedFcn = createCallbackFcn(app, @TipsandTricksMenuSelected, true);
            app.TipsandTricksMenu.Text = 'Tips and Tricks';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.Tooltip = {'Import Parameters'};
            app.PushTool.ClickedCallback = createCallbackFcn(app, @PushToolClicked, true);
            app.PushTool.Icon = 'Capture.PNG';
            app.PushTool2 = uipushtool(app.Toolbar);
            app.PushTool2.Tooltip = {'Export Parameters'};
            app.PushTool2.ClickedCallback = createCallbackFcn(app, @PushTool2Clicked, true);
            app.PushTool2.Icon = 'Capture2.PNG';
            app.CommandWindowTextArea = uitextarea(app.UIFigure);
            app.CommandWindowTextArea.Editable = 'off';
            app.CommandWindowTextArea.Placeholder = 'Welcome to Obelos!';
            app.CommandWindowLabel = uilabel(app.UIFigure);
            app.CommandWindowLabel.Text = 'Command Window';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Output1and2and3Tab = uitab(app.TabGroup);
            app.Output1and2and3Tab.Title = 'Output 1 and 2 and 3';
            app.ModelParametersPanel = uipanel(app.Output1and2and3Tab);
            app.ModelParametersPanel.Title = 'Model Parameters';
            app.domEditFieldLabel = uilabel(app.ModelParametersPanel);
            app.domEditFieldLabel.Text = 'do (m)';
            app.domEditField = uieditfield(app.ModelParametersPanel, 'numeric');
            app.nEditFieldLabel = uilabel(app.ModelParametersPanel);
            app.nEditFieldLabel.Text = 'n';
            app.nEditField = uieditfield(app.ModelParametersPanel, 'numeric');
            app.EditFieldLabel = uilabel(app.ModelParametersPanel);
            app.EditFieldLabel.Text = 'σ';
            app.EditField = uieditfield(app.ModelParametersPanel, 'numeric');
            app.PLdoEditFieldLabel = uilabel(app.ModelParametersPanel);
            app.PLdoEditFieldLabel.Text = 'PL (do)';
            app.PLdoEditField = uieditfield(app.ModelParametersPanel, 'numeric');
            app.NumberofSamplesLabel = uilabel(app.ModelParametersPanel);
            app.NumberofSamplesLabel.Text = 'Number of Samples:';
            app.NumberofSamplesKnob = uiknob(app.ModelParametersPanel, 'continuous');
            app.NumberofSamplesKnob.Limits = [0 500];
            app.NumberofSamplesKnob.ValueChangingFcn = createCallbackFcn(app, @NumberofSamplesKnobValueChanging, true);
            app.NumberofSamplesKnob.Value = 50;
            app.PowerUnitsDropDownLabel = uilabel(app.ModelParametersPanel);
            app.PowerUnitsDropDownLabel.Text = 'Power Units';
            app.PowerUnitsDropDown = uidropdown(app.ModelParametersPanel);
            app.PowerUnitsDropDown.Items = {'decimal', 'dB', 'dBm'};
            app.PowerUnitsDropDown.ValueChangedFcn = createCallbackFcn(app, @PowerUnitsDropDownValueChanged, true);
            app.PowerUnitsDropDown.Value = 'decimal';
            app.Label = uilabel(app.ModelParametersPanel);
            app.Label.Text = '50';
            app.CalculateButton = uibutton(app.ModelParametersPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.OutputsPanel = uipanel(app.Output1and2and3Tab);
            app.OutputsPanel.Title = 'Outputs';
            app.UIAxes = uiaxes(app.OutputsPanel);
            xlabel(app.UIAxes, 'distance d (m)')
            ylabel(app.UIAxes, 'Path Loss')
            zlabel(app.UIAxes, 'Z')
            app.EnterButton = uibutton(app.OutputsPanel, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.dmEditFieldLabel = uilabel(app.OutputsPanel);
            app.dmEditFieldLabel.Text = 'd (m)';
            app.dmEditField = uieditfield(app.OutputsPanel, 'numeric');
            app.MeasurementsPanel = uipanel(app.Output1and2and3Tab);
            app.MeasurementsPanel.Title = 'Measurements';
            app.UITable = uitable(app.MeasurementsPanel);
            app.UITable.ColumnName = {'d (m)'; 'Pr(d)'};
            app.UITable.RowName = {};
            app.Output4Tab = uitab(app.TabGroup);
            app.Output4Tab.Title = 'Output 4';
            app.AntennaParametersPanel = uipanel(app.Output4Tab);
            app.AntennaParametersPanel.Title = 'Antenna Parameters';
            app.PtEditFieldLabel = uilabel(app.AntennaParametersPanel);
            app.PtEditFieldLabel.Text = 'Pt ';
            app.PtEditField = uieditfield(app.AntennaParametersPanel, 'numeric');
            app.GtEditFieldLabel = uilabel(app.AntennaParametersPanel);
            app.GtEditFieldLabel.Text = 'Gt';
            app.GtEditField = uieditfield(app.AntennaParametersPanel, 'numeric');
            app.GrEditFieldLabel = uilabel(app.AntennaParametersPanel);
            app.GrEditFieldLabel.Text = 'Gr';
            app.GrEditField = uieditfield(app.AntennaParametersPanel, 'numeric');
            app.FMHzEditFieldLabel = uilabel(app.AntennaParametersPanel);
            app.FMHzEditFieldLabel.Text = 'F (MHz)';
            app.FMHzEditField = uieditfield(app.AntennaParametersPanel, 'numeric');
            app.ExtraParametersPanel = uipanel(app.Output4Tab);
            app.ExtraParametersPanel.Title = 'Extra Parameters';
            app.RequiredPREditFieldLabel = uilabel(app.ExtraParametersPanel);
            app.RequiredPREditFieldLabel.Text = 'Required PR';
            app.RequiredPREditField = uieditfield(app.ExtraParametersPanel, 'numeric');
            app.TimeEditFieldLabel = uilabel(app.ExtraParametersPanel);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.ExtraParametersPanel, 'numeric');
            app.CalculateButtonGroup = uibuttongroup(app.ExtraParametersPanel);
            app.CalculateButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CalculateButtonGroupSelectionChanged, true);
            app.CalculateButtonGroup.Title = 'Calculate';
            app.TimeButton = uiradiobutton(app.CalculateButtonGroup);
            app.TimeButton.Text = 'Time';
            app.TimeButton.Value = true;
            app.SeperationButton = uiradiobutton(app.CalculateButtonGroup);
            app.SeperationButton.Text = 'Seperation';
            app.ModelParametersPanel_2 = uipanel(app.Output4Tab);
            app.ModelParametersPanel_2.Title = 'Model Parameters';
            app.domEditField_2Label = uilabel(app.ModelParametersPanel_2);
            app.domEditField_2Label.Text = 'do (m)';
            app.domEditField_2 = uieditfield(app.ModelParametersPanel_2, 'numeric');
            app.dEditFieldLabel = uilabel(app.ModelParametersPanel_2);
            app.dEditFieldLabel.Text = 'd';
            app.dEditField = uieditfield(app.ModelParametersPanel_2, 'numeric');
            app.nEditField_2Label = uilabel(app.ModelParametersPanel_2);
            app.nEditField_2Label.Text = 'n';
            app.nEditField_2 = uieditfield(app.ModelParametersPanel_2, 'numeric');
            app.EditField_2Label = uilabel(app.ModelParametersPanel_2);
            app.EditField_2Label.Text = 'σ';
            app.EditField_2 = uieditfield(app.ModelParametersPanel_2, 'numeric');
            app.NumberofSamplesKnob_2Label = uilabel(app.ModelParametersPanel_2);
            app.NumberofSamplesKnob_2Label.Text = 'Number of Samples:';
            app.NumberofSamplesKnob_2 = uiknob(app.ModelParametersPanel_2, 'continuous');
            app.NumberofSamplesKnob_2.Limits = [0 500];
            app.NumberofSamplesKnob_2.Value = 50;
            app.Label_2 = uilabel(app.ModelParametersPanel_2);
            app.Label_2.Text = '50';
            app.ReportButton = uibutton(app.Output4Tab, 'push');
            app.ReportButton.ButtonPushedFcn = createCallbackFcn(app, @ReportButtonPushed, true);
            app.ReportButton.Text = 'Report';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainapp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end